classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Image               matlab.ui.control.Image
        CaeserCipherEncrypterDecrypterLabel  matlab.ui.control.Label
        EncryptButton       matlab.ui.control.Button
        TextAreaLabel       matlab.ui.control.Label
        EncryptionTextArea  matlab.ui.control.TextArea
        TextArea2Label      matlab.ui.control.Label
        DecryptionTextArea  matlab.ui.control.TextArea
        DecryptButton       matlab.ui.control.Button
        SpinnerLabel        matlab.ui.control.Label
        Spinner             matlab.ui.control.Spinner
        Spinner2            matlab.ui.control.Spinner
        TextArea3Label      matlab.ui.control.Label
        TextArea3           matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EncryptButton
        function EncryptButtonPushed(app, event)
            app.TextArea3.Value = '';
            str = app.EncryptionTextArea.Value;
            
            length_of_text = size(str);
            keyshift = app.Spinner.Value;
            encryptedtext = '';
            for i = 1:length_of_text
                str_temp = str{i};
                for j = 1:strlength(str_temp)
                    if double(str_temp(j)) >= 65 && double(str_temp(j)) <= 90
                        encryptedtext = append(encryptedtext, char(mod(double(str_temp(j) + keyshift - 65), 26) + 65));
                    elseif double(str_temp(j)) >= 97 && double(str_temp(j)) <= 122
                        encryptedtext = append(encryptedtext, char(mod(double(str_temp(j) + keyshift - 97), 26) + 97));
                    else
                        encryptedtext = append(encryptedtext, str_temp(j));
                    end
                end
                encryptedtext = append(encryptedtext, newline);
            end
            msgbox('Encryption Successful');
            app.TextArea3.Value = encryptedtext;
        end

        % Value changed function: EncryptionTextArea
        function EncryptionTextAreaValueChanged(app, event)
 
        end

        % Callback function
        function EncryptKeyValueChanged(app, event)
            value = app.EncryptKey.Value;
        end

        % Value changed function: DecryptionTextArea
        function DecryptionTextAreaValueChanged(app, event)
            value = app.DecryptionTextArea.Value;
        end

        % Button pushed function: DecryptButton
        function DecryptButtonPushed(app, event)
            app.TextArea3.Value = '';
            str = app.DecryptionTextArea.Value;
            
            length_of_text = size(str);
            keyshift = app.Spinner2.Value;
            decryptedtext = '';
            for i = 1:length_of_text
                str_temp = str{i};
                for j = 1:strlength(str_temp)
                    if double(str_temp(j)) >= 65 && double(str_temp(j)) <= 90
                        decryptedtext = append(decryptedtext, char(mod(double(str_temp(j) - keyshift - 65), 26) + 65));
                    elseif double(str_temp(j)) >= 97 && double(str_temp(j)) <= 122
                        decryptedtext = append(decryptedtext, char(mod(double(str_temp(j) - keyshift - 97), 26) + 97));
                    else
                        decryptedtext = append(decryptedtext, str_temp(j));
                    end
                end
                decryptedtext = append(decryptedtext, newline);
            end
            msgbox('Decryption Successful');
            app.TextArea3.Value = decryptedtext;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'cryptology-vs-cryptography-feature.jpg';
            app.CaeserCipherEncrypterDecrypterLabel = uilabel(app.UIFigure);
            app.CaeserCipherEncrypterDecrypterLabel.Text = '    Caeser Cipher Encrypter/Decrypter';
            app.EncryptButton = uibutton(app.UIFigure, 'push');
            app.EncryptButton.ButtonPushedFcn = createCallbackFcn(app, @EncryptButtonPushed, true);
            app.EncryptButton.Text = 'Encrypt';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.EncryptionTextArea = uitextarea(app.UIFigure);
            app.EncryptionTextArea.ValueChangedFcn = createCallbackFcn(app, @EncryptionTextAreaValueChanged, true);
            app.TextArea2Label = uilabel(app.UIFigure);
            app.TextArea2Label.Text = 'Text Area2';
            app.DecryptionTextArea = uitextarea(app.UIFigure);
            app.DecryptionTextArea.ValueChangedFcn = createCallbackFcn(app, @DecryptionTextAreaValueChanged, true);
            app.DecryptButton = uibutton(app.UIFigure, 'push');
            app.DecryptButton.ButtonPushedFcn = createCallbackFcn(app, @DecryptButtonPushed, true);
            app.DecryptButton.Text = 'Decrypt';
            app.SpinnerLabel = uilabel(app.UIFigure);
            app.SpinnerLabel.Text = 'Spinner';
            app.Spinner = uispinner(app.UIFigure);
            app.Spinner2 = uispinner(app.UIFigure);
            app.TextArea3Label = uilabel(app.UIFigure);
            app.TextArea3Label.Text = 'Text Area3';
            app.TextArea3 = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end